classdef teddy_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Step3Panel                 matlab.ui.container.Panel
        Export2symmetricpartsforeasyprintingCheckBox  matlab.ui.control.CheckBox
        ExportSTLButton            matlab.ui.control.Button
        Step2Panel                 matlab.ui.container.Panel
        ShowDebugImagesCheckBox    matlab.ui.control.CheckBox
        Generate3DModelButton      matlab.ui.control.Button
        Step1Panel                 matlab.ui.container.Panel
        LoadBackgroundImageButton  matlab.ui.control.Button
        StartSketchButton          matlab.ui.control.Button
        LoadBinaryImageButton      matlab.ui.control.Button
        ModeDropDown               matlab.ui.control.DropDown
        ModeLabel                  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        roi % ROI
        bdryVerts % vertices on the boundary
        binaryImgPath % binary image full path
        TEMP_VERTS = 'tmp_verts' % intermidiary verts to be saved for debug purpose
    end
    
    methods (Access = private)
        
        function resetUIAxes(app)
            for i = 1: length(app.UIAxes.Children)
                delete(app.UIAxes.Children(1));
            end
                
            app.UIAxes.PlotBoxAspectRatio = [1 1 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.Box = 'on';
            app.UIAxes.Position = [27 -1 959 969];  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes);
            axis(app.UIAxes,'equal');
            value = app.ModeDropDown.Value;
            switch value
                case 'Plain Sketch'
                    % Gray out the "Load Binary Image" button
                    app.LoadBinaryImageButton.Enable='off';
                    app.LoadBackgroundImageButton.Enable='off';
                    app.StartSketchButton.Enable='on';
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            resetUIAxes(app);
            value = app.ModeDropDown.Value;            
            switch value
                case 'Plain Sketch'
                    app.LoadBinaryImageButton.Enable='off';
                    app.LoadBackgroundImageButton.Enable='off';
                    app.StartSketchButton.Enable='on';
                    
                    
                case 'Overlapped Sketch'
                    app.LoadBinaryImageButton.Enable='off';
                    app.LoadBackgroundImageButton.Enable='on';
                    app.StartSketchButton.Enable='on';                    
                case 'Load Binary Image'
                    app.LoadBinaryImageButton.Enable='on';
                    app.LoadBackgroundImageButton.Enable='off';
                    app.StartSketchButton.Enable='off';
            end
        end

        % Button pushed function: Generate3DModelButton
        function Generate3DModelButtonPushed(app, event)
            set(app.roi, "InteractionsAllowed", "none");
            
            % Extract boundary vertices
            value = app.ModeDropDown.Value;            
            switch value
                case 'Plain Sketch'
                    app.bdryVerts = ExtractVertices(app.roi);   
                    
                case 'Overlapped Sketch'
                case 'Load Binary Image'
                    app.bdryVerts = TraceBdry(app.binaryImgPath);                
            end
            
            % triangulation
            tri = Triangulation(app.bdryVerts);
            
            % Save intermediary results for debug purpose
            verts = app.bdryVerts;
            save (app.TEMP_VERTS,'tri','verts');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 1. Create chordal axis
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             FILE_NAME2 = sprintf('%s-new-tri', app.TEMP_VERTS);
            DEBUG_CHORDAL_AXIS = app.ShowDebugImagesCheckBox.Value;
            [ chords, newTri, newVerts, entryRow, entryCol, chordSpine ] = constructChord ( tri, verts, DEBUG_CHORDAL_AXIS );
            
%             if (~DEBUG_LOAD_NEW_TRI_FROM_FILE)
%                 [ chords, newTri, newVerts, entryRow, entryCol, chordSpine ] = constructChord ( tri, verts, DEBUG_CHORDAL_AXIS );
%                 save (FILE_NAME2,'tri','verts','chords','newTri','newVerts', 'entryRow', 'entryCol', 'chordSpine');
%             else
%                 load (FILE_NAME2,'tri','verts','chords','newTri','newVerts', 'entryRow', 'entryCol', 'chordSpine');
%             end
                
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 2. Elevate the pruned axis
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % number of segments in an elliptical arc
            numSeg = 10;
            
            % elevation scale
            elevScale = 1.3;
            
            DEBUG_ELEVATION = app.ShowDebugImagesCheckBox.Value;
            
            [ verts3D, tri3D, figHandle] = Elevate ( numSeg, elevScale, verts, newVerts, newTri, chordSpine, entryRow,entryCol, DEBUG_ELEVATION);
%             if (DEBUG_WRITE_STL)
%                 % write to *.stl file
%                 FILE_NAME3 = sprintf('%s.stl',FILE_NAME);
%                 Write2Stl(verts3D,tri3D,FILE_NAME3);
%             end
        
        end

        % Button pushed function: StartSketchButton
        function StartSketchButtonPushed(app, event)
            app.roi = drawfreehand(app.UIAxes);                
        end

        % Button pushed function: LoadBackgroundImageButton
        function LoadBackgroundImageButtonPushed(app, event)
            resetUIAxes(app);
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp'}, 'Select File to Open');
            imgPath = [filepath, filename];
            img = imread(imgPath);
            h=imagesc(img,'Parent',app.UIAxes);            
            hold(app.UIAxes, 'on');
            set(h,'AlphaData',0.3);     
        end

        % Button pushed function: LoadBinaryImageButton
        function LoadBinaryImageButtonPushed(app, event)
            % load from img
            resetUIAxes(app);
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp'}, 'Select File to Open');
            app.binaryImgPath = [filepath, filename];   
            imshow(app.binaryImgPath, 'Parent', app.UIAxes);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sketch Area')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = '';
            app.Step1Panel = uipanel(app.UIFigure);
            app.Step1Panel.Title = 'Step1';
            app.ModeLabel = uilabel(app.Step1Panel);
            app.ModeLabel.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.Step1Panel);
            app.ModeDropDown.Items = {'Plain Sketch', 'Overlapped Sketch', 'Load Binary Image'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Plain Sketch';
            app.LoadBinaryImageButton = uibutton(app.Step1Panel, 'push');
            app.LoadBinaryImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBinaryImageButtonPushed, true);
            app.LoadBinaryImageButton.Text = 'Load Binary Image';
            app.StartSketchButton = uibutton(app.Step1Panel, 'push');
            app.StartSketchButton.ButtonPushedFcn = createCallbackFcn(app, @StartSketchButtonPushed, true);
            app.StartSketchButton.Text = 'Start Sketch';
            app.LoadBackgroundImageButton = uibutton(app.Step1Panel, 'push');
            app.LoadBackgroundImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBackgroundImageButtonPushed, true);
            app.LoadBackgroundImageButton.Text = 'Load Background Image';
            app.Step2Panel = uipanel(app.UIFigure);
            app.Step2Panel.Title = 'Step2';
            app.Generate3DModelButton = uibutton(app.Step2Panel, 'push');
            app.Generate3DModelButton.ButtonPushedFcn = createCallbackFcn(app, @Generate3DModelButtonPushed, true);
            app.Generate3DModelButton.Text = 'Generate 3D Model';
            app.ShowDebugImagesCheckBox = uicheckbox(app.Step2Panel);
            app.ShowDebugImagesCheckBox.Text = 'Show Debug Images';
            app.Step3Panel = uipanel(app.UIFigure);
            app.Step3Panel.Title = 'Step3';
            app.ExportSTLButton = uibutton(app.Step3Panel, 'push');
            app.ExportSTLButton.Text = 'Export STL';
            app.Export2symmetricpartsforeasyprintingCheckBox = uicheckbox(app.Step3Panel);
            app.Export2symmetricpartsforeasyprintingCheckBox.Text = {'Export 2  symmetric parts '; '(for easy printing)'};
            app.Export2symmetricpartsforeasyprintingCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = teddy_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end